classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditField_4             matlab.ui.control.NumericEditField
        EditField_4Label        matlab.ui.control.Label
        EditField_3             matlab.ui.control.NumericEditField
        EditField_3Label        matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ProcessButton           matlab.ui.control.Button
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        ModeDropDown            matlab.ui.control.DropDown
        ModeDropDownLabel       matlab.ui.control.Label
        GetImageButton          matlab.ui.control.Button
        MainMenuDropDown        matlab.ui.control.DropDown
        MainMenuDropDownLabel   matlab.ui.control.Label
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function process(app)
            cla(app.UIAxes_2);
            if app.FilenameEditField.Value == ""
                app.Label.Text = "ERROR: Please enter image file";
                return
            end
            image = imread(app.FilenameEditField.Value);
            imshow(image, "Parent", app.UIAxes);
            mainMenu = app.MainMenuDropDown.Value;
            mode = app.ModeDropDown.Value;
            switch mainMenu
                case "Smoothing"
                    maskSize = app.EditField_3.Value;
                    if maskSize <= 0 || ~mod(maskSize, 2)
                        app.Label.Text = "ERROR: Mask size must be odd positive integer";
                        return
                    end
                    if mode == "Mean Filter"
                        result = spatialSmoothing(image, maskSize, "mean");
                    elseif mode == "Gaussian Filter"
                        result = spatialSmoothing(image, maskSize, "gaussian");
                    end
                case "Lowpass Filter"
                    D0 = app.EditField_3.Value;
                    if D0 < 0
                        app.Label.Text = "ERROR: D0 must be greater or equal zero";
                        return
                    end
                    if mode == "Ideal"
                        result = lowpassFilter(image, D0, "ilpf", false);
                    elseif mode == "Gaussian"
                        result = lowpassFilter(image, D0, "glpf", false);
                    elseif mode == "Butterworth"
                        result = lowpassFilter(image, D0, "blpf", false);
                    end
                case "Highpass Filter"
                    D0 = app.EditField_3.Value;
                    if D0 < 0
                        app.Label.Text = "ERROR: D0 must be greater or equal zero";
                        return
                    end
                    if mode == "Ideal"
                        result = lowpassFilter(image, D0, "ilpf", true);
                    elseif mode == "Gaussian"
                        result = lowpassFilter(image, D0, "glpf", true);
                    elseif mode == "Butterworth"
                        result = lowpassFilter(image, D0, "blpf", true);
                    end
                case "Brightening"
                    a = app.EditField_3.Value;
                    b = app.EditField_4.Value;
                    if a < 0
                        app.Label.Text = "ERROR: a must be greater or equal zero";
                        return
                    elseif b < 0
                        app.Label.Text = "ERROR: a must be greater or equal zero";
                        return
                    end
                    result = brighteningFrequency(image, a, b);
                case "Noise Reduction"
                    maskSize = app.EditField_3.Value;
                    if maskSize <= 0 || ~mod(maskSize, 2)
                        app.Label.Text = "ERROR: Mask size must be odd positive integer";
                        return
                    end
                    switch mode
                        case "Min"
                            [result, noise] = saltPepperReduction(image, "min", maskSize);
                        case "Max"
                            [result, noise] = saltPepperReduction(image, "max", maskSize);
                        case "Median"
                            [result, noise] = saltPepperReduction(image, "median", maskSize);
                        case "Arithmetic"
                            [result, noise] = saltPepperReduction(image, "arithmeticMean", maskSize);
                        case "Geometric"
                            [result, noise] = saltPepperReduction(image, "geometric", maskSize);
                        case "Harmonic"
                            [result, noise] = saltPepperReduction(image, "harmonicMean", maskSize);
                        case "Contra-harmonic"
                            q = app.EditField_4.Value;
                            if q == ""
                                app.Label.Text = "ERROR: Please enter q";
                                return
                            end
                            q = double(q);
                            [result, noise] = saltPepperReduction(image, "contraHarmonicMean", maskSize, q);
                        case "Midpoint"
                            [result, noise] = saltPepperReduction(image, "midpoint", maskSize);
                        case "Alpha-trimmed"
                            d = app.EditField_4.Value;
                            if d == ""
                                app.Label.Text = "ERROR: Please enter d";
                                return
                            end
                            d = double(d);
                            if d < 0 || d >= maskSize^2
                                app.Label.Text = "ERROR: 0 <= d < (mask size)^2";
                                return
                            end
                            [result, noise] = saltPepperReduction(image, "alphaTrimmedMean", maskSize);
                    end
                    imshow(noise, 'Parent', app.UIAxes);
            end
            imshow(result, "Parent", app.UIAxes_2);
            app.Label.Text = sprintf("INFO: Program finished. menu='" + mainMenu + "', mode='" + mode + "'");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            app.Label.Text = "";            
            [path, canceled] = imgetfile();
            if canceled
                figure(app.UIFigure);
                return
            end
            app.FilenameEditField.Value = path;
            image = imread(path);
            imshow(image, 'Parent', app.UIAxes);
            figure(app.UIFigure);
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            process(app);
        end

        % Value changed function: MainMenuDropDown
        function MainMenuDropDownValueChanged(app, event)
            value = app.MainMenuDropDown.Value;
            app.EditField_4.Visible = "off";
            app.EditField_4Label.Visible = "off";
            app.ModeDropDown.Enable = "on";
            switch value
                case "Lowpass Filter"
                    app.ModeDropDown.Items = ["Ideal" "Gaussian" "Butterworth"];
                    app.EditField_3Label.Text = "D0";
                case "Highpass Filter"
                    app.ModeDropDown.Items = ["Ideal" "Gaussian" "Butterworth"];
                    app.EditField_3Label.Text = "D0";
                case "Brightening"
                    app.ModeDropDown.Items = "";
                    app.ModeDropDown.Enable = "off";
                    app.EditField_3Label.Text = "a";
                    app.EditField_4.Visible = "on";
                    app.EditField_4Label.Visible = "on";
                    app.EditField_4Label.Text = "b";
                case "Noise Reduction"
                    app.ModeDropDown.Items = ["Min" "Max" "Median" "Arithmetic" "Geometric" "Harmonic" "Contra-harmonic" "Midpoint" "Alpha-trimmed"];
                    app.EditField_3Label.Text = "Mask Size";
                case "Smoothing"
                    app.ModeDropDown.Items = ["Mean Filter" "Gaussian Filter"];
                    app.EditField_3Label.Text = "Mask Size";
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            app.EditField_4.Visible = "off";
            app.EditField_4Label.Visible = "off";
            value = app.ModeDropDown.Value;
            menu = app.MainMenuDropDown.Value;
            if menu == "Noise Reduction"
                if value == "Contra-harmonic"
                    app.EditField_4.Visible = "on";
                    app.EditField_4Label.Visible = "on";
                    app.EditField_4Label.Text = "q";
                elseif value == "Alpha-trimmed"
                    app.EditField_4.Visible = "on";
                    app.EditField_4Label.Visible = "on";
                    app.EditField_4Label.Text = "d";
                end
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.MainMenuDropDownLabel = uilabel(app.UIFigure);
            app.MainMenuDropDownLabel.Text = 'Main Menu';
            app.MainMenuDropDown = uidropdown(app.UIFigure);
            app.MainMenuDropDown.Items = {'Smoothing', 'Lowpass Filter', 'Highpass Filter', 'Brightening', 'Noise Reduction'};
            app.MainMenuDropDown.ValueChangedFcn = createCallbackFcn(app, @MainMenuDropDownValueChanged, true);
            app.MainMenuDropDown.Value = 'Smoothing';
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Mean Filter', 'Gaussian Filter'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Mean Filter';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Mask Size';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Visible = 'off';
            app.EditField_4Label.Text = 'q';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end